classdef UserInterface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        YredEditField             matlab.ui.control.NumericEditField
        YredEditFieldLabel        matlab.ui.control.Label
        XredEditField             matlab.ui.control.NumericEditField
        XredEditFieldLabel        matlab.ui.control.Label
        TATTOOButton              matlab.ui.control.Button
        InterpolateButton         matlab.ui.control.Button
        ResolutionEditField       matlab.ui.control.NumericEditField
        ResolutionEditFieldLabel  matlab.ui.control.Label
        Switch                    matlab.ui.control.Switch
        SmoothingEditField        matlab.ui.control.NumericEditField
        SmoothingEditFieldLabel   matlab.ui.control.Label
        GetScanDataButton         matlab.ui.control.Button
        PlotScanAreaButton        matlab.ui.control.Button
        YResEditField             matlab.ui.control.NumericEditField
        YResEditFieldLabel        matlab.ui.control.Label
        TResEditField             matlab.ui.control.NumericEditField
        TResEditFieldLabel        matlab.ui.control.Label
        Y2EditField               matlab.ui.control.NumericEditField
        Y2EditFieldLabel          matlab.ui.control.Label
        Y1EditField               matlab.ui.control.NumericEditField
        Y1EditFieldLabel          matlab.ui.control.Label
        T2EditField               matlab.ui.control.NumericEditField
        T2EditFieldLabel          matlab.ui.control.Label
        T1EditField               matlab.ui.control.NumericEditField
        T1EditFieldLabel          matlab.ui.control.Label
        MoveYEditField            matlab.ui.control.NumericEditField
        MoveYEditFieldLabel       matlab.ui.control.Label
        MoveXEditField            matlab.ui.control.NumericEditField
        MoveXEditFieldLabel       matlab.ui.control.Label
        RHopEditField             matlab.ui.control.NumericEditField
        RHopEditFieldLabel        matlab.ui.control.Label
        ProjectButton             matlab.ui.control.Button
        ScaleEditField            matlab.ui.control.NumericEditField
        ScaleEditFieldLabel       matlab.ui.control.Label
        GetXYfromImageButton      matlab.ui.control.Button
        ImageButton               matlab.ui.control.Button
        ImageAxis                 matlab.ui.control.UIAxes
        UIAxes                    matlab.ui.control.UIAxes
    end


    properties (Access = private)
        ImageFile % Description
        Paths % Description
        CylindricalPath
        Surface
        ScanPlot % Description
        ScanPath % Description
        ScannedData % Description
        highResScanPlot% Description
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ImageButton
        function ImageButtonPushed(app, event)
            [file,path] = uigetfile({'*.png';'*.jpg'},'File Selector');
            app.ImageFile = [path file];
            if ~isequal(file,0)
                app.UIAxes.Visible ='on';
                app.GetXYfromImageButton.Visible = 'on';
                app.ScaleEditField.Visible = "on";
                app.ScaleEditFieldLabel.Visible='on';
                app.XredEditField.Visible = 'on';
                app.XredEditFieldLabel.Visible='on';
                app.YredEditField.Visible ='on';
                app.YredEditFieldLabel.Visible = 'on';
            end
        end

        % Button pushed function: GetXYfromImageButton
        function GetXYfromImageButtonPushed(app, event)
            app.Paths = getXYFromImAppUpgraded(app.ImageFile,app.ScaleEditField.Value,app.XredEditField.Value,app.YResEditField.Value);
            image = imread(app.ImageFile);
            imshow(image,'Parent',app.ImageAxis)
            cla(app.UIAxes)
            hold(app.UIAxes, 'on')
            view(app.UIAxes,2)
            for j=1:length(app.Paths)
                continuousPath = app.Paths{j};
                plot(app.UIAxes,continuousPath(:,1),continuousPath(:,2))
                title(app.UIAxes, 'Identifed 2D Path from Image');
            end
            set(app.UIAxes,'xdir','reverse');
            hold(app.UIAxes, 'off')
            axis(app.UIAxes,'equal')
            app.ImageAxis.Visible='on';
            app.MoveXEditField.Visible = "on";
            app.MoveXEditFieldLabel.Visible = "on";
            app.MoveYEditField.Visible = "on";
            app.MoveYEditFieldLabel.Visible="on";
            app.RHopEditField.Visible = 'on';
            app.RHopEditFieldLabel.Visible = 'on';
            app.ProjectButton.Visible = "on";
            app.Switch.Visible = "on";
        end

        % Button pushed function: PlotScanAreaButton
        function PlotScanAreaButtonPushed(app, event)
            [app.ScanPath,app.ScanPlot] = scanning(app.T1EditField.Value,app.T2EditField.Value, ...
                app.Y1EditField.Value,app.Y2EditField.Value,app.TResEditField.Value,app.YResEditField.Value);
            scatter3(app.ScanPlot(:,1).*(cos(app.ScanPlot(:,2)-pi/2)),app.ScanPlot(:,3),app.ScanPlot(:,1).*(sin(app.ScanPlot(:,2)-pi/2)),'Parent',app.UIAxes);
            hold(app.UIAxes,'on');
            scatter3([-40;40],[0;120],[-40;40],'Parent',app.UIAxes);
            app.UIAxes.Visible ='on';
            title(app.UIAxes,'Target Scan Region');
            axis(app.UIAxes,'equal')
            hold(app.UIAxes,'off');
            app.GetScanDataButton.Visible = 'on';
            app.SmoothingEditField.Visible = 'on';
            app.SmoothingEditFieldLabel.Visible = 'on';
        end

        % Button pushed function: GetScanDataButton
        function GetScanDataButtonPushed(app, event)
            app.ScannedData = getDataFromRatP(app.ScanPath,app.SmoothingEditField.Value);
            app.ScanPlot(:,1)= lowpass(app.ScannedData-mean(app.ScannedData),app.SmoothingEditField.Value)+mean(app.ScannedData);
            app.ScanPlot = sortrows(app.ScanPlot,3);
            scatter3(app.ScanPlot(:,1).*(cos(app.ScanPlot(:,2)-pi/2)),app.ScanPlot(:,3),app.ScanPlot(:,1).*(sin(app.ScanPlot(:,2)-pi/2)),'Parent',app.UIAxes);
            app.InterpolateButton.Visible ='on';
            app.ResolutionEditFieldLabel.Visible='on';
            app.ResolutionEditField.Visible = 'on';
        end

        % Button pushed function: InterpolateButton
        function InterpolateButtonPushed(app, event)
            app.highResScanPlot = improveSurfaceResolution(app.ScanPlot,app.ResolutionEditField.Value);
            scatter3((app.highResScanPlot(:,1)).*(cos(app.highResScanPlot(:,2)-pi/2)),app.highResScanPlot(:,3),(app.highResScanPlot(:,1)).*(sin(app.highResScanPlot(:,2)-pi/2)),'Parent',app.UIAxes);
        end

        % Button pushed function: ProjectButton
        function ProjectButtonPushed(app, event)
            if(strcmp(app.Switch.Value,'Theoretical'))
                surface = createSurface(1,35,150,100,100); %%INPUT is 0 for cylinder, 1 for non-cylinder
            else
                surface = app.highResScanPlot;
                app.TATTOOButton.Visible = 'on';
            end
            [app.CylindricalPath, app.Surface] = imageProjectionApp( ...
                app.RHopEditField.Value,app.MoveXEditField.Value,app.MoveYEditField.Value,app.Paths,surface);
            scatter3(app.Surface(:,1),app.Surface(:,3), ...
                app.Surface(:,2),'filled','Parent',app.UIAxes)
            set(app.UIAxes,'xdir','normal');
            axis(app.UIAxes,'equal')
            title(app.UIAxes, '');
            hold(app.UIAxes, 'on')
            cyPath = app.CylindricalPath;
            plot3(cyPath(:,1).*cos(cyPath(:,2)-pi/2),cyPath(:,3),cyPath(:,1).*sin(cyPath(:,2)-pi/2), ...
                '-o','Color','b','MarkerSize',7,'MarkerFaceColor','#D9FFFF','Parent',app.UIAxes);
            hold(app.UIAxes, 'off')
        end

        % Button pushed function: TATTOOButton
        function TATTOOButtonPushed(app, event)
            sendTattoo(app.CylindricalPath);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.Visible = 'off';
            app.ImageAxis = uiaxes(app.UIFigure);
            app.ImageAxis.XTick = [];
            app.ImageAxis.YTick = [];
            app.ImageAxis.ZTick = [];
            app.ImageAxis.Visible = 'off';
            app.ImageButton = uibutton(app.UIFigure, 'push');
            app.ImageButton.ButtonPushedFcn = createCallbackFcn(app, @ImageButtonPushed, true);
            app.ImageButton.Text = 'Image';
            app.GetXYfromImageButton = uibutton(app.UIFigure, 'push');
            app.GetXYfromImageButton.ButtonPushedFcn = createCallbackFcn(app, @GetXYfromImageButtonPushed, true);
            app.GetXYfromImageButton.Visible = 'off';
            app.GetXYfromImageButton.Text = 'GetXYfromImage';
            app.ScaleEditFieldLabel = uilabel(app.UIFigure);
            app.ScaleEditFieldLabel.Visible = 'off';
            app.ScaleEditFieldLabel.Text = 'Scale';
            app.ScaleEditField = uieditfield(app.UIFigure, 'numeric');
            app.ScaleEditField.Limits = [0.01 10];
            app.ScaleEditField.Visible = 'off';
            app.ScaleEditField.Value = 0.6;
            app.ProjectButton = uibutton(app.UIFigure, 'push');
            app.ProjectButton.ButtonPushedFcn = createCallbackFcn(app, @ProjectButtonPushed, true);
            app.ProjectButton.Visible = 'off';
            app.ProjectButton.Text = 'Project';
            app.RHopEditFieldLabel = uilabel(app.UIFigure);
            app.RHopEditFieldLabel.Visible = 'off';
            app.RHopEditFieldLabel.Text = 'RHop';
            app.RHopEditField = uieditfield(app.UIFigure, 'numeric');
            app.RHopEditField.Limits = [1 20];
            app.RHopEditField.Visible = 'off';
            app.RHopEditField.Value = 10;
            app.MoveXEditFieldLabel = uilabel(app.UIFigure);
            app.MoveXEditFieldLabel.Visible = 'off';
            app.MoveXEditFieldLabel.Text = 'MoveX';
            app.MoveXEditField = uieditfield(app.UIFigure, 'numeric');
            app.MoveXEditField.Visible = 'off';
            app.MoveYEditFieldLabel = uilabel(app.UIFigure);
            app.MoveYEditFieldLabel.Visible = 'off';
            app.MoveYEditFieldLabel.Text = 'MoveY';
            app.MoveYEditField = uieditfield(app.UIFigure, 'numeric');
            app.MoveYEditField.Visible = 'off';
            app.T1EditFieldLabel = uilabel(app.UIFigure);
            app.T1EditFieldLabel.Text = 'T1';
            app.T1EditField = uieditfield(app.UIFigure, 'numeric');
            app.T1EditField.Limits = [0 360];
            app.T2EditFieldLabel = uilabel(app.UIFigure);
            app.T2EditFieldLabel.Text = 'T2';
            app.T2EditField = uieditfield(app.UIFigure, 'numeric');
            app.T2EditField.Limits = [0 360];
            app.Y1EditFieldLabel = uilabel(app.UIFigure);
            app.Y1EditFieldLabel.Text = 'Y1';
            app.Y1EditField = uieditfield(app.UIFigure, 'numeric');
            app.Y1EditField.Limits = [0 250];
            app.Y2EditFieldLabel = uilabel(app.UIFigure);
            app.Y2EditFieldLabel.Text = 'Y2';
            app.Y2EditField = uieditfield(app.UIFigure, 'numeric');
            app.Y2EditField.Limits = [0 120];
            app.TResEditFieldLabel = uilabel(app.UIFigure);
            app.TResEditFieldLabel.Text = 'T Res';
            app.TResEditField = uieditfield(app.UIFigure, 'numeric');
            app.TResEditField.Limits = [1 360];
            app.TResEditField.Value = 1;
            app.YResEditFieldLabel = uilabel(app.UIFigure);
            app.YResEditFieldLabel.Text = 'Y Res';
            app.YResEditField = uieditfield(app.UIFigure, 'numeric');
            app.YResEditField.Limits = [1 250];
            app.YResEditField.Value = 1;
            app.PlotScanAreaButton = uibutton(app.UIFigure, 'push');
            app.PlotScanAreaButton.ButtonPushedFcn = createCallbackFcn(app, @PlotScanAreaButtonPushed, true);
            app.PlotScanAreaButton.Text = 'Plot Scan Area';
            app.GetScanDataButton = uibutton(app.UIFigure, 'push');
            app.GetScanDataButton.ButtonPushedFcn = createCallbackFcn(app, @GetScanDataButtonPushed, true);
            app.GetScanDataButton.Visible = 'off';
            app.GetScanDataButton.Text = 'Get Scan Data';
            app.SmoothingEditFieldLabel = uilabel(app.UIFigure);
            app.SmoothingEditFieldLabel.Visible = 'off';
            app.SmoothingEditFieldLabel.Text = 'Smoothing';
            app.SmoothingEditField = uieditfield(app.UIFigure, 'numeric');
            app.SmoothingEditField.Limits = [0 1];
            app.SmoothingEditField.Visible = 'off';
            app.SmoothingEditField.Value = 0.01;
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.Items = {'Theoretical', 'Data'};
            app.Switch.Visible = 'off';
            app.Switch.Value = 'Theoretical';
            app.ResolutionEditFieldLabel = uilabel(app.UIFigure);
            app.ResolutionEditFieldLabel.Visible = 'off';
            app.ResolutionEditFieldLabel.Text = 'Resolution';
            app.ResolutionEditField = uieditfield(app.UIFigure, 'numeric');
            app.ResolutionEditField.Limits = [20 1000];
            app.ResolutionEditField.Visible = 'off';
            app.ResolutionEditField.Value = 100;
            app.InterpolateButton = uibutton(app.UIFigure, 'push');
            app.InterpolateButton.ButtonPushedFcn = createCallbackFcn(app, @InterpolateButtonPushed, true);
            app.InterpolateButton.Visible = 'off';
            app.InterpolateButton.Text = 'Interpolate';
            app.TATTOOButton = uibutton(app.UIFigure, 'push');
            app.TATTOOButton.ButtonPushedFcn = createCallbackFcn(app, @TATTOOButtonPushed, true);
            app.TATTOOButton.Visible = 'off';
            app.TATTOOButton.Text = 'TATTOO';
            app.XredEditFieldLabel = uilabel(app.UIFigure);
            app.XredEditFieldLabel.Visible = 'off';
            app.XredEditFieldLabel.Text = 'Xred';
            app.XredEditField = uieditfield(app.UIFigure, 'numeric');
            app.XredEditField.Limits = [1 10];
            app.XredEditField.Visible = 'off';
            app.XredEditField.Value = 4;
            app.YredEditFieldLabel = uilabel(app.UIFigure);
            app.YredEditFieldLabel.Visible = 'off';
            app.YredEditFieldLabel.Text = 'Yred';
            app.YredEditField = uieditfield(app.UIFigure, 'numeric');
            app.YredEditField.Limits = [1 10];
            app.YredEditField.Visible = 'off';
            app.YredEditField.Value = 2;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = UserInterface

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end